classdef HexControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LampPanel                       matlab.ui.container.Panel
        EStopStatusLamp                 matlab.ui.control.Lamp
        EStopStatusLampLabel            matlab.ui.control.Label
        AllLimitSwitchesClearLamp       matlab.ui.control.Lamp
        AllLimitSwitchesClearLampLabel  matlab.ui.control.Label
        ReadyToRunLamp                  matlab.ui.control.Lamp
        ReadyToRunLampLabel             matlab.ui.control.Label
        MotionProfileOKLamp             matlab.ui.control.Lamp
        MotionProfileOKLampLabel        matlab.ui.control.Label
        AtHomeLamp                      matlab.ui.control.Lamp
        AtHomeLampLabel                 matlab.ui.control.Label
        PositionKnownLamp               matlab.ui.control.Lamp
        PositionKnownLampLabel          matlab.ui.control.Label
        ControllerInitializedLamp       matlab.ui.control.Lamp
        ControllerInitializedLampLabel  matlab.ui.control.Label
        QueryControllerStatusButton     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        StartandHomeTab                 matlab.ui.container.Tab
        SetDatumToHomeButton            matlab.ui.control.Button
        SetDatumHereButton              matlab.ui.control.Button
        UNLOCKBrakesButton              matlab.ui.control.Button
        LOCKBrakesButton                matlab.ui.control.Button
        ReHomeActuatorsButton           matlab.ui.control.Button
        AngleIncrementEditField         matlab.ui.control.NumericEditField
        AngleIncrementEditFieldLabel    matlab.ui.control.Label
        JogDistancemmEditField          matlab.ui.control.NumericEditField
        JogDistancemmEditFieldLabel     matlab.ui.control.Label
        RyButton_2                      matlab.ui.control.Button
        RyButton                        matlab.ui.control.Button
        RxButton_2                      matlab.ui.control.Button
        RxButton                        matlab.ui.control.Button
        RzButton_2                      matlab.ui.control.Button
        RzButton                        matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        ReturntoDatumButton             matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        ControllerResponseTextArea      matlab.ui.control.TextArea
        ControllerResponseTextAreaLabel  matlab.ui.control.Label
        InitializeControllerButton      matlab.ui.control.Button
        TestArticleSetUpTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CoordinateSystemTab             matlab.ui.container.Tab
        EnterButton                     matlab.ui.control.Button
        ZmmEditField                    matlab.ui.control.NumericEditField
        ZmmEditFieldLabel               matlab.ui.control.Label
        YmmEditField                    matlab.ui.control.NumericEditField
        YmmEditFieldLabel               matlab.ui.control.Label
        XmmEditField                    matlab.ui.control.NumericEditField
        XmmEditFieldLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MassDistributionTab             matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        Z_CGmEditField                  matlab.ui.control.NumericEditField
        Z_CGmEditFieldLabel             matlab.ui.control.Label
        Y_CGmEditField                  matlab.ui.control.NumericEditField
        Y_CGmEditFieldLabel             matlab.ui.control.Label
        X_CGmEditField                  matlab.ui.control.NumericEditField
        X_CGmEditFieldLabel             matlab.ui.control.Label
        EnterButton_2                   matlab.ui.control.Button
        I_zzkgm2EditField               matlab.ui.control.NumericEditField
        I_zzkgm2EditFieldLabel          matlab.ui.control.Label
        I_yykgm2EditField               matlab.ui.control.NumericEditField
        I_yykgm2EditFieldLabel          matlab.ui.control.Label
        TotalMasskgEditField            matlab.ui.control.NumericEditField
        TotalMasskgLabel                matlab.ui.control.Label
        I_xxkgm2EditField               matlab.ui.control.NumericEditField
        I_xxkgm2Label                   matlab.ui.control.Label
        ExternalForceTab                matlab.ui.container.Tab
        EnterForces                     matlab.ui.control.Button
        M_ZNmEditField                  matlab.ui.control.NumericEditField
        M_ZNmEditFieldLabel             matlab.ui.control.Label
        M_YNmEditField                  matlab.ui.control.NumericEditField
        M_YNmEditFieldLabel             matlab.ui.control.Label
        M_XNmEditField                  matlab.ui.control.NumericEditField
        M_XNmEditFieldLabel             matlab.ui.control.Label
        F_ZNEditField                   matlab.ui.control.NumericEditField
        F_ZNEditFieldLabel              matlab.ui.control.Label
        F_YNEditField                   matlab.ui.control.NumericEditField
        F_YNEditFieldLabel              matlab.ui.control.Label
        F_XNEditField                   matlab.ui.control.NumericEditField
        F_XNEditFieldLabel              matlab.ui.control.Label
        PlanMotionTab                   matlab.ui.container.Tab
        SoftStopButton                  matlab.ui.control.Button
        MotionProfileSimulationandExecutionLabel  matlab.ui.control.Label
        MotionProfileImportExportLabel  matlab.ui.control.Label
        GenerateMotionProfileLabel      matlab.ui.control.Label
        ExportmotionprofileButton       matlab.ui.control.Button
        ExecuteMotionButton             matlab.ui.control.Button
        AnimateMotionButton             matlab.ui.control.Button
        SimulateMotionButton            matlab.ui.control.Button
        ImportMotionProfilematButton    matlab.ui.control.Button
        PointtoPointMotionButton        matlab.ui.control.Button
        SinusoidalMotionProfileButton   matlab.ui.control.Button
        DiagnostisticsTab               matlab.ui.container.Tab
        SaveappvariablestoworkspaceButton  matlab.ui.control.Button
        EmulateGalilConnectionforDebuggingCheckBox  matlab.ui.control.CheckBox
        CameraTab                       matlab.ui.container.Tab
        MeasurePoseButton               matlab.ui.control.Button
        HomeGeneratePathButton          matlab.ui.control.Button
        GetCurrentPlatformPositionButton  matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        PlatformPosePanel               matlab.ui.container.Panel
        DRO_Rz                          matlab.ui.control.NumericEditField
        YawdegEditFieldLabel            matlab.ui.control.Label
        DRO_Ry                          matlab.ui.control.NumericEditField
        PitchdegEditFieldLabel          matlab.ui.control.Label
        DRO_Rx                          matlab.ui.control.NumericEditField
        RolldegEditFieldLabel           matlab.ui.control.Label
        DRO_Z                           matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        DRO_Y                           matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        DRO_X                           matlab.ui.control.NumericEditField
        XmLabel                         matlab.ui.control.Label
        DigitalReadOutPanel             matlab.ui.container.Panel
        AB6                             matlab.ui.control.NumericEditField
        AB5                             matlab.ui.control.NumericEditField
        AB4                             matlab.ui.control.NumericEditField
        AB3                             matlab.ui.control.NumericEditField
        AB2                             matlab.ui.control.NumericEditField
        AB1                             matlab.ui.control.NumericEditField
        mmLabel_2                       matlab.ui.control.Label
        Axis5Label                      matlab.ui.control.Label
        ABLabel_2                       matlab.ui.control.Label
        CDLabel                         matlab.ui.control.Label
        Axis4Label                      matlab.ui.control.Label
        Axis3Label                      matlab.ui.control.Label
        Axis2Label                      matlab.ui.control.Label
        Axis6Label                      matlab.ui.control.Label
        Axis1Label                      matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        EncoderCountLabel               matlab.ui.control.Label
        CD6                             matlab.ui.control.NumericEditField
        CD5                             matlab.ui.control.NumericEditField
        CD4                             matlab.ui.control.NumericEditField
        CD3                             matlab.ui.control.NumericEditField
        CD2                             matlab.ui.control.NumericEditField
        CD1                             matlab.ui.control.NumericEditField
        ActuatorLengthsmLabel           matlab.ui.control.Label
        JointClearanceLabel             matlab.ui.control.Label
        AxNumeric_6                     matlab.ui.control.NumericEditField
        AxNumeric_5                     matlab.ui.control.NumericEditField
        AxNumeric_4                     matlab.ui.control.NumericEditField
        AxNumeric_3                     matlab.ui.control.NumericEditField
        AxNumeric_2                     matlab.ui.control.NumericEditField
        AxNumeric_1                     matlab.ui.control.NumericEditField
        Ax6Gauge                        matlab.ui.control.LinearGauge
        Ax5Gauge                        matlab.ui.control.LinearGauge
        Ax4Gauge                        matlab.ui.control.LinearGauge
        Ax3Gauge                        matlab.ui.control.LinearGauge
        Ax2Gauge                        matlab.ui.control.LinearGauge
        Ax1Gauge                        matlab.ui.control.LinearGauge
        UIAxesHex                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        hex_obj=struct(); % Hexapod object
        hex_plot3D=struct(); %Plotting object for hexapod 3D view
        hex_setup=struct(); % Structure containing platform, model mass and external forces
        hex_status=struct(); % Structure containing system status and readiness indicators;
        hex_path=struct(); % Structure containing the motion profile description
        LED_stat=struct();
        JogDist=0;
        JogAngle=0;
        dT=2^2/1024; %Timestep used by Galil;
        ActuatorPositions=cell(0);
        LampArray=cell(0);
        g=0;
        Connected=0;
        Emulated=0;
        Joint_Interp=0;
        softStopToken   % CancelToken
        encoderTimer    % timer handle polling TP at 10 Hz
        softStopLockout   logical = false    % set on SOFTSTOP; cleared after Return to Datum completes
    end

    properties (Access = private)
        LEDlist={'ControllerInitialized','PositionKnown','AtHome','MotionProfileOK','ReadyToRun','AllLimitSwitchesClear','EStopStatus'};
        Point_to_Point_app % Description
        SineSeries_app;
    end

    methods (Access = private)

        function results = UpdateLEDs(app)
            LEDlist=app.LEDlist;
            LED_stat=app.LED_stat;
            N_lamps=numel(LEDlist);
            OnColor=[0 1 0];  OffColor=[1 0 0];
            for n=1:N_lamps
                color=OnColor*LED_stat.(LEDlist{n})+OffColor*(1-LED_stat.(LEDlist{n}));
                app.([LEDlist{n} 'Lamp']).Color=color;
            end
        end

        function results = JogPlatform(app,jogvec)
            app.ExecuteMotionButton.Enable='off';
            app.ExportmotionprofileButton.Enable='off';
            if(app.Connected)
                g=app.g;

                [app.hex_path]=JogFromCurrentPosition(app.hex_obj,app.hex_setup,app.hex_path,[jogvec]');
                [app.hex_path]=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path);
                if ~max(app.hex_path.collisioncheck)
                    app.softStopToken.reset();
                    StreamContourData_to_Galil(g, app.hex_path, 0, 0, app.softStopToken);
                    app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj.jointsep.AB=app.hex_obj.joint_separation.AB
                    app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
                    app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);
                    app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
                else
                    msgbox('U-Joint Collision Predicted');
                end
            else
                msgbox('Not connected to controller')
            end
        end
    end

    methods (Access = public)

        function [] = Grab_Point_to_PointData(app,SegTable,MotionType,DatumType)
            SegTable2=table2array(SegTable);
            N_segments=size(SegTable2,1)-1;
            MasterPath=[]; MasterTime=[];
            for n=1:N_segments
                Ttemp=[SegTable2(n,1):app.dT:SegTable2(n+1,1)];
                temppath=[];
                for j=1:6
                    switch MotionType
                        case 0
                            temppath(j,:)=linspace(SegTable2(n,j+1),SegTable2(n+1,j+1),length(Ttemp));
                        case 1
                            temppath(j,:)=squashfunction(Ttemp,SegTable2(n,j+1),SegTable2(n+1,j+1));
                    end
                end
                MasterTime=[MasterTime Ttemp];
                MasterPath=[MasterPath temppath];
            end
            if DatumType==1;
                CurrentPose=app.hex_obj.pose;
                CurrentPose(4:6)=rad2deg(CurrentPose(4:6));
                MasterPath=MasterPath-repmat(CurrentPose,1,length(MasterTime));
            end
            app.hex_path.T=MasterTime';
            app.hex_path.pose_t=MasterPath;
            app.hex_path.pose_t(4:6,:)=deg2rad(app.hex_path.pose_t(4:6,:));

        end


        function [] = Grab_SineSeriesData(app,ComponentTable,SineDuration);
            ComponentTable2=table2array(ComponentTable);
            N_components=size(ComponentTable2,1);
            MasterTime=[];

            MasterTime=[0:app.dT:SineDuration];
            MasterPath=zeros(6,size(MasterTime,2));
            for n=1:N_components
                freq=ComponentTable2(n,1)*2*pi;
                Amptemp=[ComponentTable2(n,2:7)]';
                PhaseTemp=deg2rad(ComponentTable2(n,8));
                if freq~=0;
                    temppath=Amptemp*sin(freq*MasterTime+PhaseTemp);
                else
                    temppath=Amptemp*cos(freq*MasterTime);
                end
                MasterPath=MasterPath+temppath;

            end
            ramptime=2;
            N_ramp=round(ramptime/app.dT);
            Ramp_Env=ones(size(MasterTime));
            Ramp_Env(1:N_ramp)=linspace(0,1,N_ramp);
            Ramp_Env(end-N_ramp+1:end)=linspace(1,0,N_ramp);

            for j=1:6
                MasterPath(j,:)=MasterPath(j,:).*Ramp_Env;
            end

            MasterPath(4:6,:)=deg2rad(MasterPath(4:6,:));

            app.hex_path.T=MasterTime';
            app.hex_path.pose_t=MasterPath;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.LED_stat.ControllerInitialized=0; % Description
            app.LED_stat.PositionKnown=0;
            app.LED_stat.AtHome=0;
            app.LED_stat.MotionProfileOK=0;
            app.LED_stat.ReadyToRun=0;
            app.LED_stat.AllLimitSwitchesClear=0;
            app.LED_stat.EStopStatus=0;
            app.hex_path.dt=app.dT;
            app.softStopToken = CancelToken();
            addpath('C:\Program Files (x86)\Galil\gclib\source\wrappers\python\')


            app.ActuatorPositions={app.Ax1Gauge;app.Ax2Gauge;app.Ax3Gauge;app.Ax4Gauge;app.Ax5Gauge;app.Ax6Gauge;app.AxNumeric_1;app.AxNumeric_2;app.AxNumeric_3;app.AxNumeric_4;app.AxNumeric_5;app.AxNumeric_6;app.DRO_X;app.DRO_Y;app.DRO_Z;app.DRO_Rx;app.DRO_Ry;app.DRO_Rz;app.AB1;app.AB2;app.AB3;app.AB4;app.AB5;app.AB6;app.CD1;app.CD2;app.CD3;app.CD4;app.CD5;app.CD6};
            %         app.LampArray={app.EStopStatusLamp,app.AllLimitSwitchesClearLamp,app.ReadyToRunLamp,app.MotionProfileOKLamp,app.AtHomeLamp,app.PositionKnownLamp,app.ControllerInitializedLamp};
            %

            [app.hex_obj app.hex_setup]=InitializeHexapodObject;

            app.Joint_Interp=load("JointSep_Interpolants.mat");
            app.hex_setup.Joint_Interp=app.Joint_Interp.JointSep_Interpolants;
            app.hex_plot3D.ax=app.UIAxesHex;
            app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);
            app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
            % app.ControllerInitializedLamp.Color=[0 0 0];

            UpdateLEDs(app);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            %             if max(app.hex_obj.pose)==0
xoffset = app.XmmEditField.Value / 1000;
yoffset = app.YmmEditField.Value / 1000;
zoffset = app.ZmmEditField.Value / 1000;

app.hex_obj.T_platform_POI.t = [xoffset; yoffset; zoffset];
app.hex_obj = EffectorFromPlatformPose(app.hex_obj);
app.hex_obj = InverseKinematics_hexapod(app.hex_obj, app.hex_setup);
app.hex_plot3D = hexapodGraphic_nocalc_replot(app.hex_obj, app.hex_plot3D);
app.ActuatorPositions = updateGauges(app.hex_obj, app.ActuatorPositions);
        end

        % Button pushed function: GetCurrentPlatformPositionButton
        function GetCurrentPlatformPositionButtonPushed(app, event)
            %             [P_Pose camimg]=GrabCheckerboard(app.hex_obj);
            %             [app.hex_obj]=Platform2ModelCM(app.hex_obj,P_Pose)
            %             app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            %             app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);
            %             app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
            %             imshow(camimg,'Parent',app.UIAxes3);
            %             app.LED_stat.PositionKnown=1; UpdateLEDs(app);
        end

        % Button pushed function: InitializeControllerButton
        function InitializeControllerButtonPushed(app, event)
            app.LED_stat.ControllerInitialized = 0; UpdateLEDs(app); app.Connected = 0;

            % Close any previously-open handle. No-ops safely whether app.g is
            % the initial 0, a dead py.gclib.py, or a live emulator.
            try; app.g.GClose; catch; end %#ok<NOSEMI>

            try
                g = galilFactory(app.Emulated, '192.168.42.2');
                app.g = g;
                ss = g.GInfo;
                app.ControllerResponseTextArea.Value = ss.string;
                app.LED_stat.ControllerInitialized = 1;
                UpdateLEDs(app);
                app.Connected = 1;
                % Stop any prior polling timer, then start a fresh one.
                try
                    if ~isempty(app.encoderTimer) && isvalid(app.encoderTimer)
                        stop(app.encoderTimer);
                        delete(app.encoderTimer);
                    end
                catch
                end
                app.encoderTimer = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.1, ...           % 10 Hz
                    'BusyMode',   'drop', ...
                    'TimerFcn', @(~,~) updateDigitalShadow(app));
                start(app.encoderTimer);

            catch ME
                if app.Emulated
                    app.ControllerResponseTextArea.Value = ...
                        sprintf('Emulator failed to start: %s', ME.message);
                else
                    app.ControllerResponseTextArea.Value = 'Controller not responding';
                end
                app.LED_stat.ControllerInitialized = 0.6;
                try
                    if ~isempty(app.encoderTimer) && isvalid(app.encoderTimer)
                        stop(app.encoderTimer);
                        delete(app.encoderTimer);
                    end
                catch
                end
                app.encoderTimer = [];

                UpdateLEDs(app);
            end

        end

        % Button pushed function: HomeGeneratePathButton
        function HomeGeneratePathButtonPushed(app, event)
            Tf=10;
            app.hex_path.T=[0:app.dT:Tf];
            app.hex_path.dt=app.dT;
            CurrentPos=app.hex_obj.pose;
            TargetPos=[0;0;0;0;0;0];
            app.hex_path.pose_t=[];
            for j=1:6
                app.hex_path.pose_t(j,:)=squashfunction(app.hex_path.T,CurrentPos(j),TargetPos(j));
            end
            % app.hex_obj.pose=TargetPos; app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup); app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);  app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
            app.hex_path=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path); app.hex_path=SimulateMotionProfile_dynamics(app.hex_obj,app.hex_setup,app.hex_path,0);
            [fig Check Pass]=Plot_Check_Motion(app.hex_path,app.hex_setup)
            app.LED_stat.AtHome=1;app.LED_stat.AllLimitSwitchesClear=1; UpdateLEDs(app);
        end

        % Button pushed function: PointtoPointMotionButton
        function PointtoPointMotionButtonPushed(app, event)
            app.ExecuteMotionButton.Enable='off';
            app.ExportmotionprofileButton.Enable='off';
            app.Point_to_Point_app=Point_to_Point(app,app.hex_obj)
        end

        % Button pushed function: SimulateMotionButton
        function SimulateMotionButtonPushed(app, event)
            app.hex_path=SimulateMotionProfile_kinematics(app.hex_obj,app.hex_setup,app.hex_path); app.hex_path=SimulateMotionProfile_dynamics(app.hex_obj,app.hex_setup,app.hex_path,1);
            [fig Check Pass]=Plot_Check_Motion(app.hex_path,app.hex_setup);
            if Pass
                app.ExecuteMotionButton.Enable='on';
                app.ExportmotionprofileButton.Enable='on';
            end
        end

        % Button pushed function: SinusoidalMotionProfileButton
        function SinusoidalMotionProfileButtonPushed(app, event)
            app.ExecuteMotionButton.Enable='off';
            app.ExportmotionprofileButton.Enable='off';
            app.SineSeries_app=SineSeries(app,app.hex_obj);
        end

        % Button pushed function: AnimateMotionButton
        function AnimateMotionButtonPushed(app, event)
            if ~isfield(app.hex_path, 'pose_t') || isempty(app.hex_path.pose_t)
                uialert(app.UIFigure, ...
                    'No motion profile loaded. Generate or import a path first.', ...
                    'Animate Motion');
                return
            end
            AnimateHexapod(app.hex_obj, app.hex_setup, app.hex_path);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)

            jogvec=[0 0 app.JogDist 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)

            jogvec=[0 0 -app.JogDist 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: ReturntoDatumButton
        function ReturntoDatumButtonPushed(app, event)

           if ~app.Connected
             msgbox('Not connected to controller');
             return
         end
         g = app.g;

         % Plan a smooth S-curve from current pose to datum origin.
         app.hex_path = ReturnToDatumPath(app.hex_obj, app.hex_path);
         app.hex_path = SimulateMotionProfile_kinematics(app.hex_obj, app.hex_setup, app.hex_path);

         if max(app.hex_path.collisioncheck)
             msgbox('U-joint collision predicted along return path; cannot return to datum.');
             return
         end

         app.softStopToken.reset();
         StreamContourData_to_Galil(g, app.hex_path, 0, 0, app.softStopToken);

         % Same post-motion updates as a jog completion.
         app.hex_obj.pose = app.hex_path.pose_t(:,end);
         app.hex_obj = InverseKinematics_hexapod(app.hex_obj, app.hex_setup);
         app.hex_plot3D = hexapodGraphic_nocalc_replot(app.hex_obj, app.hex_plot3D);
         app.ActuatorPositions = updateGauges(app.hex_obj, app.ActuatorPositions);

         % Lockout cleared on successful return - Execute Motion re-enabled.
         app.softStopLockout = false;
         app.ControllerResponseTextArea.Value = 'At datum. Motion execution re-enabled.';


        end

        % Button pushed function: RyButton_2
        function RyButton_2Pushed(app, event)
            jogvec=[0 0 0 0 -app.JogAngle 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: RyButton
        function RyButtonPushed(app, event)
            jogvec=[0 0 0 0 app.JogAngle 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: RxButton_2
        function RxButton_2Pushed(app, event)
            jogvec=[0 0 0 -app.JogAngle 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: RxButton
        function RxButtonPushed(app, event)
            jogvec=[0 0 0 app.JogAngle 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: RzButton_2
        function RzButton_2Pushed(app, event)
            jogvec=[0 0 0 0 0 -app.JogAngle];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: RzButton
        function RzButtonPushed(app, event)
            jogvec=[0 0 0 0 0 app.JogAngle];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            jogvec=[0 -app.JogDist 0 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            jogvec=[0 app.JogDist 0 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            jogvec=[-app.JogDist 0 0 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            jogvec=[app.JogDist 0 0 0 0 0];
            JogPlatform(app,jogvec);
        end

        % Button pushed function: SaveappvariablestoworkspaceButton
        function SaveappvariablestoworkspaceButtonPushed(app, event)
            hex_obj=app.hex_obj;
            hex_setup=app.hex_setup;
            hex_path=app.hex_path;

            save_to_base(1);
        end

        % Value changed function: JogDistancemmEditField
        function JogDistancemmEditFieldValueChanged(app, event)
            value = app.JogDistancemmEditField.Value;
            app.JogDist=value/1000;
        end

        % Value changed function: AngleIncrementEditField
        function AngleIncrementEditFieldValueChanged(app, event)
            value = app.AngleIncrementEditField.Value;
            app.JogAngle=value;
        end

        % Button pushed function: ReHomeActuatorsButton
        function ReHomeActuatorsButtonPushed(app, event)
            g=app.g;
            [confretracted]=questdlg('Confirm that all actuators are RETRACTED past the homing switch','Confirm Retract','Yes','No','No');
            switch confretracted
                case 'Yes'

                    g.GCommand('SHABCEFG');
                    g.GCommand('HMABCEFG');
                    g.GCommand('BGABCEFG');

                     % Platform is physically at Home_platform after HM.
                    app.hex_obj.pose_platform = [app.hex_obj.Home_platform; 0; 0; 0];
                    app.hex_obj = EffectorFromPlatformPose(app.hex_obj);
                    app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
                    app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);
                    app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
                    app.LED_stat.PositionKnown=1; UpdateLEDs(app);
                case 'No'

            end
        end

        % Button pushed function: ExecuteMotionButton
        function ExecuteMotionButtonPushed(app, event)
            if app.softStopLockout
                uialert(app.UIFigure, ...
                    'Motion execution is locked out after a soft stop. Press "Return to Datum" first.', ...
                    'Soft-stop lockout', 'Icon', 'warning');
                return
            end
            if(app.Connected)
                g=app.g;
                app.softStopToken.reset();
                [exitcond, ~] = StreamContourData_to_Galil( ...
                    g, app.hex_path, 1, 0, app.softStopToken);
if strcmp(exitcond, 'SOFTSTOP')
    app.softStopLockout = true;
    app.ControllerResponseTextArea.Value = ...
        'Soft-stop engaged. Press "Return to Datum" before further motion.';
end
            else
                msgbox('Not connected to controller')
            end
            app.hex_obj.pose=app.hex_path.pose_t(:,end); app.hex_obj=InverseKinematics_hexapod(app.hex_obj,app.hex_setup);
            app.hex_plot3D=hexapodGraphic_nocalc_replot(app.hex_obj,app.hex_plot3D);
            app.ActuatorPositions=updateGauges(app.hex_obj,app.ActuatorPositions);
        end

        % Value changed function: 
        % EmulateGalilConnectionforDebuggingCheckBox
        function EmulateGalilConnectionforDebuggingCheckBoxValueChanged(app, event)
            app.Emulated = app.EmulateGalilConnectionforDebuggingCheckBox.Value;
            % Toggling emulation invalidates any previous controller handle.
            % Press Initialize Controller to bind a fresh handle (emulator or
            % real) via galilFactory.
            app.Connected = 0;
            app.LED_stat.ControllerInitialized = 0;
            UpdateLEDs(app);
        end

        % Button pushed function: EnterForces
        function EnterForcesButtonPushed(app, event)
            app.hex_setp.F_ex.Fx=app.F_XNEditField.Value;
            app.hex_setp.F_ex.Fy=app.F_YNEditField.Value;
            app.hex_setp.F_ex.Fz=app.F_ZNEditField.Value;
            app.hex_setp.F_ex.Mx=app.M_XNmEditField.Value;
            app.hex_setp.F_ex.My=app.M_YNmEditField.Value;
            app.hex_setp.F_ex.Mz=app.M_ZNmEditField.Value;
        end

        % Button pushed function: ExportmotionprofileButton
        function ExportmotionprofileButtonPushed(app, event)
            hex_path=app.hex_path;
            [fname_exp fpath_exp]=uiputfile('*.mat','Choose File Save Location');
            save([fpath_exp fname_exp],'hex_path');
        end

        % Button pushed function: ImportMotionProfilematButton
        function ImportMotionProfilematButtonPushed(app, event)
            [fname_imp fpath_imp]=uigetfile('*.mat','Choose File to Import. Select multiple files to chain motions together.','MultiSelect','on');
            if ischar(fname_imp)
                fname_imp={fname_imp};
            end
            N_imp=length(fname_imp);
            pose_master=[];
            for n=1:N_imp;
                load([fpath_imp fname_imp{n}]);
                pose_master=[pose_master hex_path.pose_t];
            end
            t_master=[1:size(pose_master,2)]'*app.dT;
            hex_path.T=t_master;
            hex_path.pose_t=pose_master;
            app.hex_path=hex_path;
        end

        % Button pushed function: UNLOCKBrakesButton
        function UNLOCKBrakesButtonPushed(app, event)
            if app.Connected

                g=app.g;
                g.GCommand('SHABCEFG');

            else
                msgbox('Not connected to Galil controller')
            end
        end

        % Button pushed function: LOCKBrakesButton
        function LOCKBrakesButtonPushed(app, event)
            if app.Connected

                g=app.g;
                g.GCommand('ST');
                g.GCommand('MO');

            else
                msgbox('Not connected to Galil controller')
            end
        end

        % Button pushed function: QueryControllerStatusButton
        function QueryControllerStatusButtonPushed(app, event)
            if(app.Connected)
                g=app.g;

                app.LED_stat.ControllerInitialized=1; UpdateLEDs(app);

            else
                app.LED_stat.ControllerInitialized=0; UpdateLEDs(app);
            end
        end

        % Button pushed function: MeasurePoseButton
        function MeasurePoseButtonPushed(app, event)
            [app.hex_obj, status] = MeasurePoseFromCheckerboard( ...
                app.hex_obj, app.UIAxes3);
            if status.ok
                app.ControllerResponseTextArea.Value = status.msg;
                % If a pose-readout UI group exists, push values into it here.
            else
                app.ControllerResponseTextArea.Value = status.msg;
                uialert(app.UIFigure, status.msg, 'Measure Pose', 'Icon', 'warning');
            end
        end

        % Button pushed function: SoftStopButton
        function SoftStopButtonPushed(app, event)
            app.softStopToken.request();
            app.ControllerResponseTextArea.Value = ...
                'Soft stop requested; ramping down over next 1 s...';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.encoderTimer) && isvalid(app.encoderTimer)
                    stop(app.encoderTimer);
                    delete(app.encoderTimer);
                end
            catch
            end
            try
                if ~isempty(app.g)
                    app.g.GClose;
                end
            catch
            end
            delete(app);
        end

        % Button pushed function: SetDatumHereButton
        function SetDatumHereButtonPushed(app, event)
% Take the current platform pose (world frame) as the new datum anchor.
app.hex_obj.T_world_datum_platform = poseToTransform(app.hex_obj.pose_platform);
app.hex_obj.pose = zeros(6,1);    % at the new datum by definition
app.hex_obj = InverseKinematics_hexapod(app.hex_obj, app.hex_setup);
app.hex_plot3D = hexapodGraphic_nocalc_replot(app.hex_obj, app.hex_plot3D);
app.ActuatorPositions = updateGauges(app.hex_obj, app.ActuatorPositions);
        end

        % Button pushed function: SetDatumToHomeButton
        function SetDatumToHomeButtonPushed(app, event)
           % Rebuild T_world_datum_platform at identity rotation and
    % Home_platform translation.
app.hex_obj.T_world_datum_platform = struct( ...
    'R', eye(3), ...
    't', app.hex_obj.Home_platform);
app.hex_obj = EffectorFromPlatformPose(app.hex_obj);
app.hex_obj = InverseKinematics_hexapod(app.hex_obj, app.hex_setup);
app.hex_plot3D = hexapodGraphic_nocalc_replot(app.hex_obj, app.hex_plot3D);
app.ActuatorPositions = updateGauges(app.hex_obj, app.ActuatorPositions);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesHex = uiaxes(app.UIFigure);
            title(app.UIAxesHex, 'Current Configuration')
            xlabel(app.UIAxesHex, 'X')
            ylabel(app.UIAxesHex, 'Y')
            zlabel(app.UIAxesHex, 'Z')
            app.DigitalReadOutPanel = uipanel(app.UIFigure);
            app.DigitalReadOutPanel.Title = 'Digital Read Out';
            app.Ax1Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.Ax1Gauge.Limits = [0 1];
            app.Ax2Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.Ax3Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.Ax4Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.Ax5Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.Ax6Gauge = uigauge(app.DigitalReadOutPanel, 'linear');
            app.AxNumeric_1 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_1.ValueDisplayFormat = '%.0f';
            app.AxNumeric_1.Editable = 'off';
            app.AxNumeric_2 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_2.ValueDisplayFormat = '%.0f';
            app.AxNumeric_2.Editable = 'off';
            app.AxNumeric_3 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_3.ValueDisplayFormat = '%.0f';
            app.AxNumeric_3.Editable = 'off';
            app.AxNumeric_4 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_4.ValueDisplayFormat = '%.0f';
            app.AxNumeric_4.Editable = 'off';
            app.AxNumeric_5 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_5.ValueDisplayFormat = '%.0f';
            app.AxNumeric_5.Editable = 'off';
            app.AxNumeric_6 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AxNumeric_6.ValueDisplayFormat = '%.0f';
            app.AxNumeric_6.Editable = 'off';
            app.JointClearanceLabel = uilabel(app.DigitalReadOutPanel);
            app.JointClearanceLabel.Text = {'Joint'; 'Clearance'};
            app.ActuatorLengthsmLabel = uilabel(app.DigitalReadOutPanel);
            app.ActuatorLengthsmLabel.Text = {'Actuator '; 'Lengths [m]'};
            app.CD1 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD1.ValueDisplayFormat = '%.3f';
            app.CD1.Editable = 'off';
            app.CD2 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD2.ValueDisplayFormat = '%.3f';
            app.CD2.Editable = 'off';
            app.CD3 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD3.ValueDisplayFormat = '%.3f';
            app.CD3.Editable = 'off';
            app.CD4 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD4.ValueDisplayFormat = '%.3f';
            app.CD4.Editable = 'off';
            app.CD5 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD5.ValueDisplayFormat = '%.3f';
            app.CD5.Editable = 'off';
            app.CD6 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.CD6.ValueDisplayFormat = '%.3f';
            app.CD6.Editable = 'off';
            app.EncoderCountLabel = uilabel(app.DigitalReadOutPanel);
            app.EncoderCountLabel.Text = 'Encoder Count';
            app.mmLabel = uilabel(app.DigitalReadOutPanel);
            app.mmLabel.Text = 'mm';
            app.Axis1Label = uilabel(app.DigitalReadOutPanel);
            app.Axis1Label.Text = 'Axis 1';
            app.Axis6Label = uilabel(app.DigitalReadOutPanel);
            app.Axis6Label.Text = 'Axis 6';
            app.Axis2Label = uilabel(app.DigitalReadOutPanel);
            app.Axis2Label.Text = 'Axis 2';
            app.Axis3Label = uilabel(app.DigitalReadOutPanel);
            app.Axis3Label.Text = 'Axis 3';
            app.Axis4Label = uilabel(app.DigitalReadOutPanel);
            app.Axis4Label.Text = 'Axis 4';
            app.CDLabel = uilabel(app.DigitalReadOutPanel);
            app.CDLabel.Text = 'CD';
            app.ABLabel_2 = uilabel(app.DigitalReadOutPanel);
            app.ABLabel_2.Text = 'AB';
            app.Axis5Label = uilabel(app.DigitalReadOutPanel);
            app.Axis5Label.Text = 'Axis 5';
            app.mmLabel_2 = uilabel(app.DigitalReadOutPanel);
            app.mmLabel_2.Text = 'mm';
            app.AB1 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB1.ValueDisplayFormat = '%.3f';
            app.AB1.Editable = 'off';
            app.AB2 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB2.ValueDisplayFormat = '%.3f';
            app.AB2.Editable = 'off';
            app.AB3 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB3.ValueDisplayFormat = '%.3f';
            app.AB3.Editable = 'off';
            app.AB4 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB4.ValueDisplayFormat = '%.3f';
            app.AB4.Editable = 'off';
            app.AB5 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB5.ValueDisplayFormat = '%.3f';
            app.AB5.Editable = 'off';
            app.AB6 = uieditfield(app.DigitalReadOutPanel, 'numeric');
            app.AB6.ValueDisplayFormat = '%.3f';
            app.AB6.Editable = 'off';
            app.PlatformPosePanel = uipanel(app.UIFigure);
            app.PlatformPosePanel.Title = 'Platform Pose';
            app.XmLabel = uilabel(app.PlatformPosePanel);
            app.XmLabel.Text = 'X (m)';
            app.DRO_X = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_X.Editable = 'off';
            app.YmEditFieldLabel = uilabel(app.PlatformPosePanel);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.DRO_Y = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_Y.Editable = 'off';
            app.ZmEditFieldLabel = uilabel(app.PlatformPosePanel);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.DRO_Z = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_Z.Editable = 'off';
            app.RolldegEditFieldLabel = uilabel(app.PlatformPosePanel);
            app.RolldegEditFieldLabel.Text = 'Roll (deg)';
            app.DRO_Rx = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_Rx.Editable = 'off';
            app.PitchdegEditFieldLabel = uilabel(app.PlatformPosePanel);
            app.PitchdegEditFieldLabel.Text = 'Pitch (deg)';
            app.DRO_Ry = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_Ry.Editable = 'off';
            app.YawdegEditFieldLabel = uilabel(app.PlatformPosePanel);
            app.YawdegEditFieldLabel.Text = 'Yaw (deg)';
            app.DRO_Rz = uieditfield(app.PlatformPosePanel, 'numeric');
            app.DRO_Rz.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartandHomeTab = uitab(app.TabGroup);
            app.StartandHomeTab.Title = 'Start and Home';
            app.InitializeControllerButton = uibutton(app.StartandHomeTab, 'push');
            app.InitializeControllerButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeControllerButtonPushed, true);
            app.InitializeControllerButton.Text = 'Initialize Controller';
            app.ControllerResponseTextAreaLabel = uilabel(app.StartandHomeTab);
            app.ControllerResponseTextAreaLabel.Text = 'Controller Response';
            app.ControllerResponseTextArea = uitextarea(app.StartandHomeTab);
            app.ZButton = uibutton(app.StartandHomeTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.ReturntoDatumButton = uibutton(app.StartandHomeTab, 'push');
            app.ReturntoDatumButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoDatumButtonPushed, true);
            app.ReturntoDatumButton.Text = {'Return to '; 'Datum'};
            app.XButton = uibutton(app.StartandHomeTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.StartandHomeTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.RzButton = uibutton(app.StartandHomeTab, 'push');
            app.RzButton.ButtonPushedFcn = createCallbackFcn(app, @RzButtonPushed, true);
            app.RzButton.Text = '+Rz';
            app.RzButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.RzButton_2.ButtonPushedFcn = createCallbackFcn(app, @RzButton_2Pushed, true);
            app.RzButton_2.Text = '-Rz';
            app.RxButton = uibutton(app.StartandHomeTab, 'push');
            app.RxButton.ButtonPushedFcn = createCallbackFcn(app, @RxButtonPushed, true);
            app.RxButton.Text = '+Rx';
            app.RxButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.RxButton_2.ButtonPushedFcn = createCallbackFcn(app, @RxButton_2Pushed, true);
            app.RxButton_2.Text = '-Rx';
            app.RyButton = uibutton(app.StartandHomeTab, 'push');
            app.RyButton.ButtonPushedFcn = createCallbackFcn(app, @RyButtonPushed, true);
            app.RyButton.Text = '+Ry';
            app.RyButton_2 = uibutton(app.StartandHomeTab, 'push');
            app.RyButton_2.ButtonPushedFcn = createCallbackFcn(app, @RyButton_2Pushed, true);
            app.RyButton_2.Text = '-Ry';
            app.JogDistancemmEditFieldLabel = uilabel(app.StartandHomeTab);
            app.JogDistancemmEditFieldLabel.Text = 'Jog Distance (mm)';
            app.JogDistancemmEditField = uieditfield(app.StartandHomeTab, 'numeric');
            app.JogDistancemmEditField.Limits = [0 100];
            app.JogDistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @JogDistancemmEditFieldValueChanged, true);
            app.AngleIncrementEditFieldLabel = uilabel(app.StartandHomeTab);
            app.AngleIncrementEditFieldLabel.Text = 'Angle Increment';
            app.AngleIncrementEditField = uieditfield(app.StartandHomeTab, 'numeric');
            app.AngleIncrementEditField.Limits = [0 10];
            app.AngleIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @AngleIncrementEditFieldValueChanged, true);
            app.ReHomeActuatorsButton = uibutton(app.StartandHomeTab, 'push');
            app.ReHomeActuatorsButton.ButtonPushedFcn = createCallbackFcn(app, @ReHomeActuatorsButtonPushed, true);
            app.ReHomeActuatorsButton.Text = 'Re-Home Actuators';
            app.LOCKBrakesButton = uibutton(app.StartandHomeTab, 'push');
            app.LOCKBrakesButton.ButtonPushedFcn = createCallbackFcn(app, @LOCKBrakesButtonPushed, true);
            app.LOCKBrakesButton.Text = {'LOCK'; 'Brakes'};
            app.UNLOCKBrakesButton = uibutton(app.StartandHomeTab, 'push');
            app.UNLOCKBrakesButton.ButtonPushedFcn = createCallbackFcn(app, @UNLOCKBrakesButtonPushed, true);
            app.UNLOCKBrakesButton.Text = {'UNLOCK'; 'Brakes'};
            app.SetDatumHereButton = uibutton(app.StartandHomeTab, 'push');
            app.SetDatumHereButton.ButtonPushedFcn = createCallbackFcn(app, @SetDatumHereButtonPushed, true);
            app.SetDatumHereButton.Tooltip = {'Sets current pose as the datum for programmed motions. Use to establish an alteranate "home" position offset from the hardcoded machine home.'};
            app.SetDatumHereButton.Text = {'Set Datum '; 'Here'};
            app.SetDatumToHomeButton = uibutton(app.StartandHomeTab, 'push');
            app.SetDatumToHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SetDatumToHomeButtonPushed, true);
            app.SetDatumToHomeButton.Tooltip = {'Sets current pose as the datum for programmed motions. Use to establish an alteranate "home" position offset from the hardcoded machine home.'};
            app.SetDatumToHomeButton.Text = {'Set Datum '; 'To Home'};
            app.TestArticleSetUpTab = uitab(app.TabGroup);
            app.TestArticleSetUpTab.Title = 'Test Article Set-Up';
            app.TabGroup2 = uitabgroup(app.TestArticleSetUpTab);
            app.CoordinateSystemTab = uitab(app.TabGroup2);
            app.CoordinateSystemTab.Title = 'Coordinate System';
            app.Label = uilabel(app.CoordinateSystemTab);
            app.Label.Text = {'Define the location (in the global coordinates shown below) of the local coordinate frame of the object under test.'; ''; 'All motions will be defined about the local frame.'};
            app.XmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.YmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.ZmmEditFieldLabel = uilabel(app.CoordinateSystemTab);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.CoordinateSystemTab, 'numeric');
            app.EnterButton = uibutton(app.CoordinateSystemTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.MassDistributionTab = uitab(app.TabGroup2);
            app.MassDistributionTab.Title = 'Mass Distribution';
            app.I_xxkgm2Label = uilabel(app.MassDistributionTab);
            app.I_xxkgm2Label.Interpreter = 'latex';
            app.I_xxkgm2Label.Text = '$I_{xx}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_xxkgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.TotalMasskgLabel = uilabel(app.MassDistributionTab);
            app.TotalMasskgLabel.Text = 'Total Mass                   kg';
            app.TotalMasskgEditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.I_yykgm2EditFieldLabel = uilabel(app.MassDistributionTab);
            app.I_yykgm2EditFieldLabel.Interpreter = 'latex';
            app.I_yykgm2EditFieldLabel.Text = '$I_{yy}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_yykgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.I_zzkgm2EditFieldLabel = uilabel(app.MassDistributionTab);
            app.I_zzkgm2EditFieldLabel.Interpreter = 'latex';
            app.I_zzkgm2EditFieldLabel.Text = '$I_{zz}$\ \ \ \ \ \ \ \ \ \ \  kg-m$^2$';
            app.I_zzkgm2EditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.EnterButton_2 = uibutton(app.MassDistributionTab, 'push');
            app.EnterButton_2.Text = 'Enter';
            app.X_CGmEditFieldLabel = uilabel(app.MassDistributionTab);
            app.X_CGmEditFieldLabel.Interpreter = 'latex';
            app.X_CGmEditFieldLabel.Text = '$X_{CG}$\ \ \ \ \ \ \ \ \ \ \  m';
            app.X_CGmEditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.Y_CGmEditFieldLabel = uilabel(app.MassDistributionTab);
            app.Y_CGmEditFieldLabel.Interpreter = 'latex';
            app.Y_CGmEditFieldLabel.Text = '$Y_{CG}$\ \ \ \ \ \ \ \ \ \ \  m';
            app.Y_CGmEditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.Z_CGmEditFieldLabel = uilabel(app.MassDistributionTab);
            app.Z_CGmEditFieldLabel.Interpreter = 'latex';
            app.Z_CGmEditFieldLabel.Text = '$Z_{CG}$\ \ \ \ \ \ \ \ \ \ \  m';
            app.Z_CGmEditField = uieditfield(app.MassDistributionTab, 'numeric');
            app.Label_2 = uilabel(app.MassDistributionTab);
            app.Label_2.Text = 'Include the CG location and the mass moment of inertia relative to the platform origin.';
            app.ExternalForceTab = uitab(app.TabGroup2);
            app.ExternalForceTab.Title = 'External Force';
            app.F_XNEditFieldLabel = uilabel(app.ExternalForceTab);
            app.F_XNEditFieldLabel.Interpreter = 'latex';
            app.F_XNEditFieldLabel.Text = '$F_X$\ \ \ \ \ \ \ \ \ \ \  N';
            app.F_XNEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.F_YNEditFieldLabel = uilabel(app.ExternalForceTab);
            app.F_YNEditFieldLabel.Interpreter = 'latex';
            app.F_YNEditFieldLabel.Text = '$F_Y$\ \ \ \ \ \ \ \ \ \ \  N';
            app.F_YNEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.F_ZNEditFieldLabel = uilabel(app.ExternalForceTab);
            app.F_ZNEditFieldLabel.Interpreter = 'latex';
            app.F_ZNEditFieldLabel.Text = '$F_Z$\ \ \ \ \ \ \ \ \ \ \  N';
            app.F_ZNEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.M_XNmEditFieldLabel = uilabel(app.ExternalForceTab);
            app.M_XNmEditFieldLabel.Interpreter = 'latex';
            app.M_XNmEditFieldLabel.Text = '$M_X$\ \ \ \ \ \ \ \ \ \ \  N-m';
            app.M_XNmEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.M_YNmEditFieldLabel = uilabel(app.ExternalForceTab);
            app.M_YNmEditFieldLabel.Interpreter = 'latex';
            app.M_YNmEditFieldLabel.Text = '$M_Y$\ \ \ \ \ \ \ \ \ \ \  N-m';
            app.M_YNmEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.M_ZNmEditFieldLabel = uilabel(app.ExternalForceTab);
            app.M_ZNmEditFieldLabel.Interpreter = 'latex';
            app.M_ZNmEditFieldLabel.Text = '$M_Z$\ \ \ \ \ \ \ \ \ \ \  N-m';
            app.M_ZNmEditField = uieditfield(app.ExternalForceTab, 'numeric');
            app.EnterForces = uibutton(app.ExternalForceTab, 'push');
            app.EnterForces.ButtonPushedFcn = createCallbackFcn(app, @EnterForcesButtonPushed, true);
            app.EnterForces.Text = 'Enter';
            app.PlanMotionTab = uitab(app.TabGroup);
            app.PlanMotionTab.Title = 'Plan Motion';
            app.SinusoidalMotionProfileButton = uibutton(app.PlanMotionTab, 'push');
            app.SinusoidalMotionProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalMotionProfileButtonPushed, true);
            app.SinusoidalMotionProfileButton.Text = 'Sinusoidal Motion Profile';
            app.PointtoPointMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.PointtoPointMotionButton.ButtonPushedFcn = createCallbackFcn(app, @PointtoPointMotionButtonPushed, true);
            app.PointtoPointMotionButton.Text = 'Point-to-Point Motion';
            app.ImportMotionProfilematButton = uibutton(app.PlanMotionTab, 'push');
            app.ImportMotionProfilematButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMotionProfilematButtonPushed, true);
            app.ImportMotionProfilematButton.Text = 'Import Motion Profile (.mat)';
            app.SimulateMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.SimulateMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateMotionButtonPushed, true);
            app.SimulateMotionButton.Text = 'Simulate Motion';
            app.AnimateMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.AnimateMotionButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateMotionButtonPushed, true);
            app.AnimateMotionButton.Text = 'Animate Motion';
            app.ExecuteMotionButton = uibutton(app.PlanMotionTab, 'push');
            app.ExecuteMotionButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteMotionButtonPushed, true);
            app.ExecuteMotionButton.Enable = 'off';
            app.ExecuteMotionButton.Text = 'Execute Motion';
            app.ExportmotionprofileButton = uibutton(app.PlanMotionTab, 'push');
            app.ExportmotionprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportmotionprofileButtonPushed, true);
            app.ExportmotionprofileButton.Enable = 'off';
            app.ExportmotionprofileButton.Text = 'Export motion profile';
            app.GenerateMotionProfileLabel = uilabel(app.PlanMotionTab);
            app.GenerateMotionProfileLabel.Text = 'Generate Motion Profile';
            app.MotionProfileImportExportLabel = uilabel(app.PlanMotionTab);
            app.MotionProfileImportExportLabel.Text = 'Motion Profile Import/Export';
            app.MotionProfileSimulationandExecutionLabel = uilabel(app.PlanMotionTab);
            app.MotionProfileSimulationandExecutionLabel.Text = 'Motion Profile Simulation and Execution';
            app.SoftStopButton = uibutton(app.PlanMotionTab, 'push');
            app.SoftStopButton.ButtonPushedFcn = createCallbackFcn(app, @SoftStopButtonPushed, true);
            app.SoftStopButton.Text = 'Soft Stop';
            app.DiagnostisticsTab = uitab(app.TabGroup);
            app.DiagnostisticsTab.Title = 'Diagnostistics';
            app.EmulateGalilConnectionforDebuggingCheckBox = uicheckbox(app.DiagnostisticsTab);
            app.EmulateGalilConnectionforDebuggingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EmulateGalilConnectionforDebuggingCheckBoxValueChanged, true);
            app.EmulateGalilConnectionforDebuggingCheckBox.Text = 'Emulate Galil Connection for Debugging';
            app.SaveappvariablestoworkspaceButton = uibutton(app.DiagnostisticsTab, 'push');
            app.SaveappvariablestoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveappvariablestoworkspaceButtonPushed, true);
            app.SaveappvariablestoworkspaceButton.Text = 'Save app variables to workspace';
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.UIAxes3 = uiaxes(app.CameraTab);
            title(app.UIAxes3, 'Latest Camera Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GetCurrentPlatformPositionButton = uibutton(app.CameraTab, 'push');
            app.GetCurrentPlatformPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetCurrentPlatformPositionButtonPushed, true);
            app.GetCurrentPlatformPositionButton.Text = {'Get Current '; 'Platform Position'};
            app.HomeGeneratePathButton = uibutton(app.CameraTab, 'push');
            app.HomeGeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @HomeGeneratePathButtonPushed, true);
            app.HomeGeneratePathButton.Text = {'Home '; '(Generate Path)'};
            app.MeasurePoseButton = uibutton(app.CameraTab, 'push');
            app.MeasurePoseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurePoseButtonPushed, true);
            app.MeasurePoseButton.Text = 'Measure Pose';
            app.LampPanel = uipanel(app.UIFigure);
            app.LampPanel.Title = 'LampPanel';
            app.QueryControllerStatusButton = uibutton(app.LampPanel, 'push');
            app.QueryControllerStatusButton.ButtonPushedFcn = createCallbackFcn(app, @QueryControllerStatusButtonPushed, true);
            app.QueryControllerStatusButton.Text = 'Query Controller Status';
            app.ControllerInitializedLampLabel = uilabel(app.LampPanel);
            app.ControllerInitializedLampLabel.Text = 'Controller Initialized';
            app.ControllerInitializedLamp = uilamp(app.LampPanel);
            app.PositionKnownLampLabel = uilabel(app.LampPanel);
            app.PositionKnownLampLabel.Text = 'Position Known';
            app.PositionKnownLamp = uilamp(app.LampPanel);
            app.AtHomeLampLabel = uilabel(app.LampPanel);
            app.AtHomeLampLabel.Text = 'At Home';
            app.AtHomeLamp = uilamp(app.LampPanel);
            app.MotionProfileOKLampLabel = uilabel(app.LampPanel);
            app.MotionProfileOKLampLabel.Text = 'Motion Profile OK';
            app.MotionProfileOKLamp = uilamp(app.LampPanel);
            app.ReadyToRunLampLabel = uilabel(app.LampPanel);
            app.ReadyToRunLampLabel.Text = 'Ready To Run';
            app.ReadyToRunLamp = uilamp(app.LampPanel);
            app.AllLimitSwitchesClearLampLabel = uilabel(app.LampPanel);
            app.AllLimitSwitchesClearLampLabel.Text = 'All Limit Switches Clear';
            app.AllLimitSwitchesClearLamp = uilamp(app.LampPanel);
            app.EStopStatusLampLabel = uilabel(app.LampPanel);
            app.EStopStatusLampLabel.Text = 'E-Stop Status';
            app.EStopStatusLamp = uilamp(app.LampPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HexControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end