classdef Hexapod_Generate_MultiSine_Excitation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ScaleFactorSpinner              matlab.ui.control.Spinner
        ScaleFactorSpinnerLabel         matlab.ui.control.Label
        NumberofRealiationsMSpinner     matlab.ui.control.Spinner
        NumberofRealiationsMSpinnerLabel  matlab.ui.control.Label
        NumberofPeriodsPSpinner         matlab.ui.control.Spinner
        NumberofPeriodsPSpinnerLabel    matlab.ui.control.Label
        MaximumFrequencyHzSpinner       matlab.ui.control.Spinner
        MaximumFrequencyHzSpinnerLabel  matlab.ui.control.Label
        GenerateMultisineButton         matlab.ui.control.Button
        MinimumFrequencyHzSpinner       matlab.ui.control.Spinner
        MinimumFrequencyLabel           matlab.ui.control.Label
        ExprimentalFrequencyBandHzSlider  matlab.ui.control.RangeSlider
        ExprimentalFrequencyBandHzSliderLabel  matlab.ui.control.Label
        Sampletime                      matlab.ui.control.NumericEditField
        SampletimeforoneperiodEditFieldLabel  matlab.ui.control.Label
        SamplingRateFsEditField         matlab.ui.control.NumericEditField
        SamplingRateFsEditFieldLabel    matlab.ui.control.Label
        NumberofInputsNuEditField       matlab.ui.control.NumericEditField
        NumberofInputsNuLabel           matlab.ui.control.Label
        SelectAllCheckBox               matlab.ui.control.CheckBox
        YawCheckBox                     matlab.ui.control.CheckBox
        PitchCheckBox                   matlab.ui.control.CheckBox
        RollCheckBox                    matlab.ui.control.CheckBox
        ZCheckBox                       matlab.ui.control.CheckBox
        YCheckBox                       matlab.ui.control.CheckBox
        XCheckBox                       matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        progressBarAxes                 matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ChooseFolderButton              matlab.ui.control.Button
        TimeforEachExperiment           matlab.ui.control.NumericEditField
        TimeforEachExperimentEditFieldLabel  matlab.ui.control.Label
        TotalTestingTime                matlab.ui.control.NumericEditField
        TotalTestingTimeEditFieldLabel  matlab.ui.control.Label
        ChooseFolderEditField           matlab.ui.control.EditField
        ExportData                      matlab.ui.control.Button
        ExampleSignal                   matlab.ui.control.UIAxes
        fftAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        nu = 0;                           % the number of inputs to simulate
        T_end = 10;                       % the number of seconds in one period of the experiment
        Fs = 256;                         % the sampling rate of the experiments in Hz (limited by speed of Hexapod)
        MaxFreq = 5;                      % the maximum frequency of the band of interest in Hz
        MinFreq = 0.1;                    % the minimum frequency of the band of interest in Hz
        minFreqRange = 0.1;               % the minimum frequency range of the band of interes in Hz
        Periods = 2;                      % the number of periods in one experiment
        Realizations = 2;                 % the number of realizations in one estimate of the FRF
        activeInputs = [];                % the active inputs for the multisine to populate
        data =  struct('dummyField',[]);  % the structure where data is stored
        scaleFactor = 1;                  % gain to increase the total number of samples by
        Dir = "";                         % where new data folders should be created
        isDir = false;                    % has the directory for data folders been specified
    end
    
    methods (Access = private)
          
        function updateActiveCheckboxes(app)
            app.activeInputs = [app.XCheckBox.Value,app.YCheckBox.Value,...
                            app.ZCheckBox.Value,app.RollCheckBox.Value,...
                            app.PitchCheckBox.Value,app.YawCheckBox.Value];
            activeCount = sum(app.activeInputs);
            app.NumberofInputsNuEditField.Value = activeCount;
            app.nu = activeCount;
            calcExperimentTime(app);
        end
        
        % initialize the progress bar
        function initProgressBar(app)
            cla(app.progressBarAxes);
            barh(app.progressBarAxes, 0, 'FaceColor', 'blue');
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.XTick = [];
            title(app.progressBarAxes, 'Generating Multisine...');
        end
        
        function updateProgressBar(app,progress)
            % Ensure progress is between 0 and 1
            progress = max(0,min(progress,1));
            barh(app.progressBarAxes, progress,'FaceColor','blue');
            app.progressBarAxes.YLim = [0.5 1.5];
            app.progressBarAxes.XLim = [0 1];
            drawnow;
        end
        
        function plotFFT(app)
            Ts = 1/app.Fs;
            N = app.T_end/Ts+1;
            freq = 1/Ts*(0:(N-1))/N; % frequency in Hz
            f = freq(1:(N/2+1));
            legendInfo = {};
            % we will just loop one experiment and realisation to not clutter the screen
            for k = 1:6
                if app.activeInputs(k)
                    % compute FFT
                    Y = fft(app.data.u.R1.E1(k,1:N));
                    P2 = abs(Y./N);
                    P1 = P2(1:(N/2+1));

                    % plot the FFT
                    %plot(app.fftAxes, freq, P2,'+');
                    plot(app.fftAxes, f, P1);
                    hold(app.fftAxes, 'on');

                    % id DOF name
                    if k == 1
                        dofName = 'X';
                    elseif k == 2
                        dofName = 'Y';
                    elseif k == 3
                        dofName = 'Z';
                    elseif k == 4
                        dofName = 'Roll';
                    elseif k == 5
                        dofName = 'Pitch';
                    else
                        dofName = 'Yaw';
                    end
                    % add to the legend
                   
                    legendInfo{end+1}= dofName;
                end
            end
            % add legend
            legend(app.fftAxes, legendInfo);
            hold(app.fftAxes,'off');
        end

        function plotMultisine(app)
            % function to plot all periods of one experiments multisine
            Ts = 1/app.Fs;
            time = 0:Ts:(app.T_end*2);
            time = time(1:end-1);
            %how many N per period
            N = (app.Fs/app.MinFreq)*app.scaleFactor; 
            % find rows of app.data.u.E1.R1 that are not zero and plot
            % the first one. There will be at least one.
            dataIndex = find(any(app.data.u.R1.E1,1),1,"first");
            multisine = app.data.u.R1.E1(dataIndex,1:(2*N));
            plot(app.ExampleSignal,time,multisine);
            hold(app.ExampleSignal,"on");
            % put vert line at first period
            xline(app.ExampleSignal,time(N)); 
            hold(app.ExampleSignal,"off");
        end
        
        function sampleTime(app)
            % calculate Sample time based on min frequency and sampling
            % rate
            N = (app.Fs/app.MinFreq)*app.scaleFactor;
            app.T_end = N/app.Fs;
            app.Sampletime.Value = app.T_end;
            calcExperimentTime(app);
        end
        
        
        function calcExperimentTime(app)
            experiment_time = app.T_end*app.Periods;
            app.TimeforEachExperiment.Value = experiment_time;
            app.TotalTestingTime.Value = app.nu*(experiment_time)*app.Realizations;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MinimumFrequencyHzSpinner.Step = app.minFreqRange;
            app.MaximumFrequencyHzSpinner.Step = app.minFreqRange;
            % set default values
            % input DOF
            app.SelectAllCheckBox.Value = 1;
            SelectAllCheckBoxValueChanged(app);
            % sampling rate
            app.SamplingRateFsEditField.Value = 256;
            % scale factor for number of samples
            app.ScaleFactorSpinner.Value = 1;
            % Min and Max Frequency
            app.MinimumFrequencyHzSpinner.Value = 0.1;
            app.MaximumFrequencyHzSpinner.Value = 5;
            % Number of Periods
            app.NumberofPeriodsPSpinner.Value = 6;
            app.NumberofPeriodsPSpinnerValueChanged(app);
            % Number of Realizations
            app.NumberofRealiationsMSpinner.Value = 10;
            app.NumberofRealiationsMSpinnerValueChanged(app);
            % calculate sample time for one period
            sampleTime(app);
        end

        % Button pushed function: GenerateMultisineButton
        function GenerateMultisineButtonPushed(app, event)
            clearvars data.u data.r
            % Validate that at least one DOF is selected
            if app.nu == 0
                uialert(app.UIFigure,'Please select at least on input.',...
                    'Input Error');
            end
            % init progress bar
            initProgressBar(app);
            % Inputs are valid, calculate Multisine
            %Ts = 1/app.Fs;
            N = (app.Fs/app.MinFreq)*app.scaleFactor;
            ExcitedHarm = round((app.MinFreq*N)/app.Fs:1:(2*app.MaxFreq*N)/app.Fs); % MinFreq - MaxFreq (hz)
            nh = length(ExcitedHarm);
            % define amplitude spectra inputs
            AmplitudeExcitedHarm = ones(app.nu, nh);
            AmplitudeExcitedHarm(:, floor(nh/2):end) = 1e-5; % relative amplitudes
            if app.nu == 1
                % special case, use SISO multisine generator
                for i = 1:app.Realizations
                    r = CalcMultisine(ExcitedHarm,N,AmplitudeExcitedHarm);
                    r = r';
                    theSignal = repmat(r,[1,app.Periods]);
                    
                    % update the progress bar
                    updateProgressBar(app, i / app.Realizations);

                    % init our 6 x 1 x N*P input signal and reference
                    theExcitation = zeros(6,length(theSignal));
                    theReference = zeros(6,N);
                    % identify which DOF and package the signal
                    activeDOFIndices = find(app.activeInputs);
                    for DOF = 1:length(activeDOFIndices)
                        dofIndex = activeDOFIndices(DOF);
                        theExcitation(dofIndex,:) = theSignal;
                        theReference(dofIndex,:) = r;
                    end
                    % package theExcitation
                    theRealization = strcat('R',string(i));
                    app.data.u.(theRealization).E1 = theExcitation;
                    app.data.r.(theRealization).E1(:,1:N) = theReference;
                end
                plotFFT(app);
                plotMultisine(app);
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished');
            else
                % general case
                nsteps = app.Realizations * app.nu;
                count = 0;
                for i = 1:app.Realizations
                    r = Calc_MIMO_Multisine(ExcitedHarm,N,AmplitudeExcitedHarm);
                    theSignal = repmat(r,[1,1,app.Periods]);

                    % init our 6 x 1 x N*P input signal and reference
                    theExcitation = zeros(6,app.nu,length(theSignal));
                    theReference = zeros(6,app.nu,N);
                    % identify which DOF and package the signal
                    activeDOFIndices = find(app.activeInputs);
                    for DOF = 1:length(activeDOFIndices)
                        dofIndex = activeDOFIndices(DOF);
                        theExcitation(dofIndex,:,:) = theSignal(DOF,:,:);
                        theReference(dofIndex,:,:) = r(DOF,:,:);
                    end
                    % Seperate each experiment
                    for j = 1:app.nu
                        theRealization = strcat('R',string(i));
                        theExperiment = strcat('E',string(j));
                        app.data.u.(theRealization).(theExperiment) = squeeze(theExcitation(:,j,:));
                        app.data.r.(theRealization).(theExperiment)(:,:,1:N) = theReference(:,j,1:N); 

                        %update the progress bar
                        count = 1 + count;
                        updateProgressBar(app, count/nsteps)
                    end
                end
                plotFFT(app);
                plotMultisine(app);
                updateProgressBar(app, 1);
                title(app.progressBarAxes,'Finished');
            end
            % remove the dummy field we needed to first init the struct
            app.data = rmfield(app.data,'dummyField');
            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.XCheckBox.Value;
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YCheckBox.Value;
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.ZCheckBox.Value;
        end

        % Value changed function: RollCheckBox
        function RollCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.RollCheckBox.Value;
        end

        % Value changed function: PitchCheckBox
        function PitchCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.PitchCheckBox.Value;
        end

        % Value changed function: YawCheckBox
        function YawCheckBoxValueChanged(app, event)
            updateActiveCheckboxes(app);
            %value = app.YawCheckBox.Value;
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            % set all check boxes to checked
            app.XCheckBox.Value = value;
            app.YCheckBox.Value = value;
            app.ZCheckBox.Value = value;
            app.RollCheckBox.Value = value;
            app.PitchCheckBox.Value = value;
            app.YawCheckBox.Value = value;
            % update the active checkboxes
            updateActiveCheckboxes(app);
        end

        % Value changing function: ExprimentalFrequencyBandHzSlider
        function ExprimentalFrequencyBandHzSliderValueChanging(app, event)
            sliderValue = event.Value;
            if sliderValue(1) < 0.1
                % we can't be exactly on 0, min tested is 0.1 Hz
                sliderValue(1) = 0.1;
            end
            app.MinimumFrequencyHzSpinner.Value = sliderValue(1);
            app.MaximumFrequencyHzSpinner.Value = sliderValue(2);
        end

        % Value changing function: MinimumFrequencyHzSpinner
        function MinimumFrequencyHzSpinnerValueChanging(app, event)
            minFreq = event.Value;
            maxFreq = app.MaximumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','error');
                maxFreq = minFreq + app.minFreqRange;
                app.MaximumFrequencyHzSpinner.Value = maxFreq;
                app.MaxFreq = maxFreq;
            end
            app.MinFreq = minFreq;
            sampleTime(app);
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq, app.MaxFreq];
        end

        % Value changing function: MaximumFrequencyHzSpinner
        function MaximumFrequencyHzSpinnerValueChanging(app, event)
            maxFreq = event.Value;
            minFreq = app.MinimumFrequencyHzSpinner.Value;
            if (maxFreq - minFreq) < app.minFreqRange
                errorMessage = sprintf('Minimum range between frequencies must be at least %.2f.',app.minFreqRange);
                uialert(app.UIFigure, errorMessage, 'Range Error','Icon','warning');
                minFreq = maxFreq - app.minFreqRange;
                if minFreq < app.MinimumFrequencyHzSpinner.Limits(1)
                    minFreq = app.MinimumFrequencyHzSpinner.Limits(1);
                    maxFreq = minFreq + app.minFreqRange;
                end
                app.MinimumFrequencyHzSpinner.Value = minFreq;
                app.MinFreq = minFreq;
                sampleTime(app)
            end
            app.MaxFreq = maxFreq;
            app.ExprimentalFrequencyBandHzSlider.Value = [app.MinFreq,app.MaxFreq];
        end

        % Value changed function: NumberofPeriodsPSpinner
        function NumberofPeriodsPSpinnerValueChanged(app, event)
            if mod(app.NumberofPeriodsPSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Periods must be an integer',...
                    'Input Error','Icon','warning');
                P = round(app.NumberofPeriodsPSpinner.Value); % must be an int
                app.NumberofPeriodsPSpinner.Value = P;
            end
            app.Periods = app.NumberofPeriodsPSpinner.Value;
        end

        % Value changed function: NumberofRealiationsMSpinner
        function NumberofRealiationsMSpinnerValueChanged(app, event)
            if mod(app.NumberofRealiationsMSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Number of Realizations must be an integer',...
                    'Input Error','Icon','warning');
                M = round(app.NumberofRealiationsMSpinner.Value); % must be int
                app.NumberofRealiationsMSpinner.Value = M;
            end
            app.Realizations = app.NumberofRealiationsMSpinner.Value;
            calcExperimentTime(app);
        end

        % Value changed function: ScaleFactorSpinner
        function ScaleFactorSpinnerValueChanged(app, event)
            if mod(app.ScaleFactorSpinner.Value, 1) ~= 0
                uialert(app.UIFigure,'Scale Factor must be an integer',...
                    'Input Error','Icon','warning');
                SF = round(app.ScaleFactorSpinner.Value);
                app.ScaleFactorSpinner.Value = SF;
            end
            app.scaleFactor = app.ScaleFactorSpinner.Value;
            sampleTime(app);
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            % bring up a UIdir to choose the folder to save the files too
            dname = uigetdir('\Desktop\');
            app.ChooseFolderEditField.Value = dname;
            app.Dir = dname;
            app.isDir = true;
        end

        % Button pushed function: ExportData
        function ExportDataButtonPushed(app, event)
            % check if the directory for saving has been specified, if not 
            % trigger the ChooseFolder action
            if ~app.isDir
                fig = uifigure;
                selection = uiconfirm(fig,"No Directory Specified!",...
                    "Invalid Directory","Icon","error");
                switch selection
                    case 'OK'
                        delete(fig);
                        pause(1); % wait a second before opening the window
                        ChooseFolderButtonPushed(app,event);
                    case 'Cancel'
                        return % exit the sequence
                end
            else
                % check that app.data.u and app.data.r exist
                if isfield(app.data,'u') && isfield(app.data,'r')
                    dateTimeStr = datetime('now','Timezone','local','Format','yyyMMdd_HHmms');
                    fileName = sprintf('Data_nu%d_P%d_R%d_%s.mat',...
                        app.nu,app.Periods,app.Realizations,dateTimeStr);
                    fullPath = fullfile(app.Dir,fileName);
                    MultiSine_Excitation = app.data;
                    try
                        save(fullPath,'MultiSine_Excitation');
                        message = sprintf('Data successfully exported as %s',fileName);
                        uialert(app.UIFigure,message,"Success",'Icon','success');
                    catch e
                        uialert(app.UIFigure, sprintf('Failed to export data: %s', e.message),'Error');
                    end
                else
                    uialert(app.UIFigure,'Multisine Data not yet created!',...
                        'Input Error');
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {684, 684};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {499, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.progressBarAxes = uiaxes(app.LeftPanel);
            title(app.progressBarAxes, 'Progress Bar')
            app.progressBarAxes.XTick = [];
            app.progressBarAxes.XTickLabel = '';
            app.progressBarAxes.YTick = [];
            app.progressBarAxes.YTickLabel = '';
            app.progressBarAxes.ZTick = [0 0.5 1];
            app.progressBarAxes.ZTickLabel = '';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Hexapod Multisine Generator'};
            app.XCheckBox = uicheckbox(app.LeftPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.YCheckBox = uicheckbox(app.LeftPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.ZCheckBox = uicheckbox(app.LeftPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.RollCheckBox = uicheckbox(app.LeftPanel);
            app.RollCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollCheckBoxValueChanged, true);
            app.RollCheckBox.Text = 'Roll';
            app.PitchCheckBox = uicheckbox(app.LeftPanel);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchCheckBoxValueChanged, true);
            app.PitchCheckBox.Text = 'Pitch';
            app.YawCheckBox = uicheckbox(app.LeftPanel);
            app.YawCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawCheckBoxValueChanged, true);
            app.YawCheckBox.Text = 'Yaw';
            app.SelectAllCheckBox = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.NumberofInputsNuLabel = uilabel(app.LeftPanel);
            app.NumberofInputsNuLabel.Text = 'Number of Inputs, Nu';
            app.NumberofInputsNuEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofInputsNuEditField.Limits = [0 6];
            app.NumberofInputsNuEditField.Editable = 'off';
            app.SamplingRateFsEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateFsEditFieldLabel.Text = 'Sampling Rate, Fs';
            app.SamplingRateFsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRateFsEditField.Limits = [0 256];
            app.SamplingRateFsEditField.Editable = 'off';
            app.SamplingRateFsEditField.Value = 256;
            app.SampletimeforoneperiodEditFieldLabel = uilabel(app.LeftPanel);
            app.SampletimeforoneperiodEditFieldLabel.Text = 'Sample time for one period ';
            app.Sampletime = uieditfield(app.LeftPanel, 'numeric');
            app.Sampletime.Limits = [0.2 300];
            app.Sampletime.Editable = 'off';
            app.Sampletime.Value = 10;
            app.ExprimentalFrequencyBandHzSliderLabel = uilabel(app.LeftPanel);
            app.ExprimentalFrequencyBandHzSliderLabel.Text = {'Exprimental '; 'Frequency Band (Hz) '};
            app.ExprimentalFrequencyBandHzSlider = uislider(app.LeftPanel, 'range');
            app.ExprimentalFrequencyBandHzSlider.Limits = [0 5];
            app.ExprimentalFrequencyBandHzSlider.ValueChangingFcn = createCallbackFcn(app, @ExprimentalFrequencyBandHzSliderValueChanging, true);
            app.ExprimentalFrequencyBandHzSlider.Value = [0 5];
            app.MinimumFrequencyLabel = uilabel(app.LeftPanel);
            app.MinimumFrequencyLabel.Text = {'Minimum '; 'Frequency (Hz)'};
            app.MinimumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MinimumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MinimumFrequencyHzSpinnerValueChanging, true);
            app.MinimumFrequencyHzSpinner.Limits = [0.1 5];
            app.MinimumFrequencyHzSpinner.Value = 0.1;
            app.GenerateMultisineButton = uibutton(app.LeftPanel, 'push');
            app.GenerateMultisineButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMultisineButtonPushed, true);
            app.GenerateMultisineButton.Text = 'Generate Multisine';
            app.MaximumFrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.MaximumFrequencyHzSpinnerLabel.Text = {'Maximum'; 'Frequency (Hz)'};
            app.MaximumFrequencyHzSpinner = uispinner(app.LeftPanel);
            app.MaximumFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @MaximumFrequencyHzSpinnerValueChanging, true);
            app.MaximumFrequencyHzSpinner.Limits = [0.2 5];
            app.MaximumFrequencyHzSpinner.Value = 5;
            app.NumberofPeriodsPSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofPeriodsPSpinnerLabel.Text = 'Number o f Periods, P';
            app.NumberofPeriodsPSpinner = uispinner(app.LeftPanel);
            app.NumberofPeriodsPSpinner.Limits = [2 60];
            app.NumberofPeriodsPSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPeriodsPSpinnerValueChanged, true);
            app.NumberofPeriodsPSpinner.Value = 6;
            app.NumberofRealiationsMSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofRealiationsMSpinnerLabel.Text = 'Number of Realiations, M';
            app.NumberofRealiationsMSpinner = uispinner(app.LeftPanel);
            app.NumberofRealiationsMSpinner.Limits = [2 10];
            app.NumberofRealiationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofRealiationsMSpinnerValueChanged, true);
            app.NumberofRealiationsMSpinner.Value = 10;
            app.ScaleFactorSpinnerLabel = uilabel(app.LeftPanel);
            app.ScaleFactorSpinnerLabel.Text = 'Scale  Factor';
            app.ScaleFactorSpinner = uispinner(app.LeftPanel);
            app.ScaleFactorSpinner.Limits = [1 30];
            app.ScaleFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorSpinnerValueChanged, true);
            app.ScaleFactorSpinner.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.fftAxes = uiaxes(app.RightPanel);
            title(app.fftAxes, 'FFT of Excitation Signals')
            xlabel(app.fftAxes, 'Frequency (Hz)')
            ylabel(app.fftAxes, 'Magnitude')
            zlabel(app.fftAxes, 'Z')
            app.fftAxes.XLim = [0 6];
            app.ExampleSignal = uiaxes(app.RightPanel);
            title(app.ExampleSignal, 'Multisine Signal')
            xlabel(app.ExampleSignal, 'Time')
            ylabel(app.ExampleSignal, 'Displacement')
            app.ExportData = uibutton(app.RightPanel, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportData.Text = 'Export .Mat';
            app.ChooseFolderEditField = uieditfield(app.RightPanel, 'text');
            app.TotalTestingTimeEditFieldLabel = uilabel(app.RightPanel);
            app.TotalTestingTimeEditFieldLabel.Text = 'Total Testing Time';
            app.TotalTestingTime = uieditfield(app.RightPanel, 'numeric');
            app.TotalTestingTime.Editable = 'off';
            app.TimeforEachExperimentEditFieldLabel = uilabel(app.RightPanel);
            app.TimeforEachExperimentEditFieldLabel.Text = 'Time for Each Experiment';
            app.TimeforEachExperiment = uieditfield(app.RightPanel, 'numeric');
            app.TimeforEachExperiment.Editable = 'off';
            app.ChooseFolderButton = uibutton(app.RightPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'Best FRF(M) estimates found when '; '''Total Testing Time''  =  nu * 600 seconds'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hexapod_Generate_MultiSine_Excitation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end