classdef Point_to_Point < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddsegmentsdescribingthemotionoftheendeffectorLabel_2  matlab.ui.control.Label
        MotionProfileTypeButtonGroup  matlab.ui.container.ButtonGroup
        SmoothedButton                matlab.ui.control.RadioButton
        TrapezoidalButton             matlab.ui.control.RadioButton
        AddsegmentsdescribingthemotionoftheendeffectorLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        AddSegmentButton              matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = public)
        Seg_Table=table(); % Motion segment table
        MotionType=0; 
    end
    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hex_obj)
            app.CallingApp=caller;
            CurrentPose=hex_obj.pose;
            TableRow=array2table([0 CurrentPose'],'VariableNames',{'Time','X','Y','Z','Rx','Ry','Rz'});
            app.UITable.Data=TableRow;
        end

        % Button pushed function: AddSegmentButton
        function AddSegmentButtonPushed(app, event)
            OldTable=app.UITable.Data;
            N_rows=size(OldTable,1);
            NewTable=[OldTable;OldTable(end,:)];
            app.Seg_Table=NewTable;
            app.UITable.Data=NewTable;
        end

        % Cell edit callback: UITable
        function UpdateTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Seg_Table=app.UITable.Data;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            Segmenttypebutton=app.MotionProfileTypeButtonGroup.SelectedObject;
        switch Segmenttypebutton.Text
            case 'Trapezoidal'
                Segmenttype=0;
            case 'Smoothed'
                Segmenttype=1;
        end

        Grab_Point_to_PointData(app.CallingApp,app.Seg_Table,Segmenttype);
        delete(app)

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'X'; 'Y'; 'Z'; 'Rx'; 'Ry'; 'Rz'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UpdateTable, true);
            app.AddSegmentButton = uibutton(app.UIFigure, 'push');
            app.AddSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentButtonPushed, true);
            app.AddSegmentButton.Text = {'Add '; 'Segment'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel.Text = {'Add segments describing the motion of the end effector. Click ''Add Segment'' to include '; 'each additional point.'};
            app.MotionProfileTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.MotionProfileTypeButtonGroup.Title = 'Motion Profile Type';
            app.TrapezoidalButton = uiradiobutton(app.MotionProfileTypeButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.TrapezoidalButton.Value = true;
            app.SmoothedButton = uiradiobutton(app.MotionProfileTypeButtonGroup);
            app.SmoothedButton.Text = 'Smoothed';
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2 = uilabel(app.UIFigure);
            app.AddsegmentsdescribingthemotionoftheendeffectorLabel_2.Text = {'Trapezoidal motion will use straight-line segments to connect each point. '; 'Smoothed motion will use a scaled hyperbolic tangent to produce smooth "S" motion segments.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Point_to_Point(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end